classdef EComUDP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        structVar=struct();
        pixData=zeros(1,832);
        eeMLX90640=zeros(1,832);
        frameData=zeros(1,835);
        tmprData=zeros(1,768);
        fileName;
        framefileID;
        fileNumber=0;
        s1;
        flag=1;
        Startflag=1;
        fileID;
        pktCnt=0;
        I; %imagesc handle
        imData=zeros(24,32);
        tempData=zeros(1,768);
        ADC;
        tmpData=zeros(1,451);
     end

    methods (Access = private)
         %------------read eeprom data-------------
         function results = ReceiveEEPROMData(app)
             temp=zeros(1,832);
             fprintf(app.s1, 1);
             temp = fread(app.s1, 832, 'uint16');
             results=temp;
         end
        %----------ExtractParameters------------------------------
        function result=MLX90640_ExtractParameters(app)
            fprintf("inside MLX90640_ExtractParameters\n");
            file='D:\test.csv';
            app.fileID = fopen(file,'w');
            error=CheckEEPROMValid(app);
            if error==0
                temp=app.ExtractVDDParameters();
                app.structVar.kVdd=temp(1);
                fprintf(app.fileID, 'kVdd %d \n',app.structVar.kVdd);
                app.structVar.vdd25=temp(2);
                temp1=app.ExtractPTATParameters();
                fprintf(app.fileID, 'vdd25 %d \n',app.structVar.vdd25);
                app.structVar.KvPTAT=temp1(1);
                fprintf(app.fileID, 'KvPTAT %d \n',app.structVar.KvPTAT);
                app.structVar.KtPTAT=temp1(2);
                fprintf(app.fileID, 'KtPTAT %d \n',app.structVar.KtPTAT);
                app.structVar.vPTAT25=temp1(3);
                fprintf(app.fileID, 'vPTAT25 %d \n',app.structVar.vPTAT25);
                app.structVar.alphaPTAT=temp1(4);
                fprintf(app.fileID, 'alphaPTAT %d \n',app.structVar.alphaPTAT);
                temp2=app.ExtractGainParameters();
                app.structVar.gainEE=temp2;
                fprintf(app.fileID, 'gainEE %d \n',app.structVar.gainEE);
                temp3=app.ExtractTgcParameters();
                app.structVar.tgc=temp3;
                fprintf(app.fileID, 'tgc %d \n',app.structVar.tgc);
                temp4=app.ExtractResolutionParameters();
                app.structVar.resolutionEE=temp4;
                fprintf(app.fileID, 'resolutionEE %d \n',app.structVar.resolutionEE);
                temp5=app.ExtractKsTaParameters();
                app.structVar.KsTa=temp5;
                fprintf(app.fileID, 'KsTa %d \n',app.structVar.KsTa);
                temp6=app.ExtractKsToParameters();
                for i=1:4
                    app.structVar.ct(i)=temp6(i);
                    fprintf(app.fileID, 'ct(%d) %d \n',i,app.structVar.ct(i));
                end
                for i=5:8
                    app.structVar.ksTo(i-4)=temp6(i);
                    fprintf(app.fileID, 'ksTo(%d) %d \n',(i-4),app.structVar.ksTo(i-4));
                end
                temp7=app.ExtractAlphaParameters();
                for i=1:768
                    app.structVar.alpha(i)=temp7(i);
                    fprintf(app.fileID, 'alpha(%d) %d \n',i,app.structVar.alpha(i));
                end
                temp8= app.ExtractOffsetParameters();
                for i=1:768
                    app.structVar.offset(i)=temp8(i);
                    fprintf(app.fileID, 'offset(%d) %d \n',i,app.structVar.offset(i));
                end
                temp9=app.ExtractKtaPixelParameters();
                for i=1:768
                    app.structVar.kta(i)=temp9(i);
                    fprintf(app.fileID, 'kta(%d) %d \n',i,app.structVar.kta(i));
                end
                temp10=app.ExtractKvPixelParameters();
                for i=1:768
                    app.structVar.kv(i)=temp10(i);
                    fprintf(app.fileID, 'kv(%d) %d \n',i,app.structVar.kv(i));
                end
                temp11= app.ExtractCPParameters();
                
                app.structVar.cpAlpha(1)=temp11(1);
                fprintf(app.fileID, 'cpAlpha(1) %d \n',app.structVar.cpAlpha(1));
                app.structVar.cpAlpha(2)=temp11(2);
                fprintf(app.fileID, 'cpAlpha(2) %d \n',app.structVar.cpAlpha(2));
                app.structVar.cpOffset(1)=temp11(3);
                fprintf(app.fileID, 'cpOffset(1) %d \n',app.structVar.cpOffset(1));
                app.structVar.cpOffset(2)=temp11(4);
                fprintf(app.fileID, 'cpOffset(2) %d \n',app.structVar.cpOffset(2));
                app.structVar.cpKv=temp11(5);
                fprintf(app.fileID, 'cpKv %d \n',app.structVar.cpKv);
                app.structVar.cpKta=temp11(6);
                fprintf(app.fileID, 'cpKta %d \n',app.structVar.cpKta);
                temp12= app.ExtractCILCParameters();
                app.structVar.calibrationModeEE=temp12(1);
                fprintf(app.fileID, 'calibrationModeEE %d \n',app.structVar.calibrationModeEE);
                app.structVar.ilChessC(1)=temp12(2);
                fprintf(app.fileID, 'ilChessC(1) %d \n',app.structVar.ilChessC(1));
                app.structVar.ilChessC(2)=temp12(3);
                fprintf(app.fileID, 'ilChessC(2) %d \n',app.structVar.ilChessC(2));
                app.structVar.ilChessC(3)=temp12(4);
                fprintf(app.fileID, 'ilChessC(3) %d \n',app.structVar.ilChessC(3));
                temp13= app.ExtractDeviatingPixels();
                for i=1:5
                    app.structVar.brokenPixels(i)=temp13(i);
                    fprintf(app.fileID, 'brokenPixels(%d) %d \n',i,app.structVar.brokenPixels(i));
                end
                for i=6:10
                    app.structVar.outlierPixels(i-5)=temp13(i);
                    fprintf(app.fileID, 'outlierPixels(%d) %d \n',i,app.structVar.outlierPixels(i-5));
                end
                result=app.structVar;
                
            else
                result=1;
                fprintf("outside MLX90640_ExtractParameters \n");
            end
            
        end
    %------------------CheckEEPROMValid------------------------------------------------
    function error=CheckEEPROMValid(app)
        %fprintf("inside CheckEEPROMValid \n");
        deviceSelect = bitand(app.eeMLX90640(11),hex2dec('0040'));
        if(deviceSelect == 0)
            error=0;
        else
            error= -7;
        end
    end
        %%-------------------------ExtractVDDParameters----------------------------------------------
        function result=ExtractVDDParameters(app)
            
            kVdd=bitand(app.eeMLX90640(52),hex2dec('FF00'));
            kVdd = bitsrl(int32(kVdd),8);
            if(kVdd > 127)
                kVdd = kVdd - 256;
            end
            kVdd = 32 * kVdd;
            temp(1)=kVdd;
            vdd25 = bitand(app.eeMLX90640(52) ,hex2dec('00FF'));
            vdd25 = bitsll((vdd25 - 256),5) - 8192;
            temp(2)=vdd25;
            result=temp;
            
        end
  %--------------- ExtractPTATParameters-----------    
        function result=ExtractPTATParameters(app)
            
            KvPTAT = bitsrl(int32(bitand(app.eeMLX90640(51),hex2dec('FC00'))),10);
            if(KvPTAT > 31)
                
                KvPTAT = KvPTAT - 64;
            end
            KvPTAT = KvPTAT/4096;
            
            KtPTAT = bitand(app.eeMLX90640(51),hex2dec('03FF'));
            if(KtPTAT > 511)
                
                KtPTAT = KtPTAT - 1024;
            end
            KtPTAT = KtPTAT/8;
            
            vPTAT25 = app.eeMLX90640(50);
            
            alphaPTAT = (bitand(app.eeMLX90640(17),hex2dec('F000'))) / pow2(double(14)) + 8.0;
            
            temp(1) = KvPTAT;
            temp(2) = KtPTAT;
            temp(3)= vPTAT25;
            temp(4) = alphaPTAT;
            result=temp;
            
        end
        
        %--------------- ExtractGainParameters-----------  
        function result= ExtractGainParameters(app)
            
            gainEE = app.eeMLX90640(49);
            if(gainEE > 32767)
                
                gainEE = gainEE -65536;
            end
            
            result= gainEE;
        end
        
        function result= ExtractTgcParameters(app)
            
            tgc = bitand(app.eeMLX90640(61),hex2dec('00FF'));
            if(tgc > 127)
                
                tgc = tgc - 256;
            end
            tgc = tgc / 32.0;
            result = tgc;
        end
    %----------------ExtractResolutionParameters-------------
        
        function result= ExtractResolutionParameters(app)
            
            resolutionEE = bitsrl(int32(bitand(app.eeMLX90640(57), hex2dec('3000'))), 12);
            result = resolutionEE;
        end
    %--------------------ExtractKsTaParameters------------------
        function result= ExtractKsTaParameters(app)
            KsTa = double(bitsrl(int32(bitand(app.eeMLX90640(61), hex2dec('FF00'))),8));
            if(KsTa > 127)
                
                KsTa = KsTa -256;
            end
            KsTa = KsTa / 8192.0;
            
            result = KsTa;
        end
  
    %----------------------ExtractKsToParameters----------------
        
        function result= ExtractKsToParameters(app)
            
            step = bitsrl(int32(bitand(app.eeMLX90640(64),hex2dec('3000'))), 12) * 10;
            
            ct(1) = -40;
            ct(2) = 0;
            ct(3)= bitsrl(int32(bitand(app.eeMLX90640(64),hex2dec('00F0'))),4);
            ct(4)= bitsrl(int32(bitand(app.eeMLX90640(64),hex2dec('0F00'))),8);
            
            ct(3) = ct(3)*step;
            ct(4) = ct(3) + ct(4)*step;
            
            KsToScale = bitand(app.eeMLX90640(64),hex2dec('000F')) + 8;
            KsToScale = bitsll(1,KsToScale);
            
            ksTo(1) = bitand(app.eeMLX90640(62) ,hex2dec('00FF'));
            ksTo(2) = bitsrl(int32(bitand(app.eeMLX90640(62) ,hex2dec('FF00'))),8);
            ksTo(3) = bitand(app.eeMLX90640(63) ,hex2dec('00FF'));
            ksTo(4) = bitsrl(int32(bitand(app.eeMLX90640(63) ,hex2dec('FF00'))),8);
            
            
            for i= 1: 4
                if(ksTo(i) > 127)
                    
                    ksTo(i) = ksTo(i) -256;
                end
                ksTo(i) = ksTo(i) / KsToScale;
            end
            
            ct(5)= ksTo(1);
            ct(6)= ksTo(2);
            ct(7)= ksTo(3);
            ct(8)= ksTo(4);
            
            result=ct;
        end
        
  %----------------------------ExtractAlphaParameters-----------------      
        function result= ExtractAlphaParameters(app)
            
            accRow=zeros(1,24);
            accColumn=zeros(1,32);
            accRemScale = bitand(app.eeMLX90640(33), hex2dec('000F'));
            accColumnScale = bitsrl(int32(bitand(app.eeMLX90640(33),hex2dec('00F0'))), 4);
            accRowScale = bitsrl(int32(bitand(app.eeMLX90640(33),hex2dec('0F00'))), 8);
            alphaScale =  bitsrl(int32(bitand(app.eeMLX90640(33),hex2dec('F000'))), 12) + 30;
            alphaRef = app.eeMLX90640(34);
            alpha=zeros(1,768);
            for i=1:6
                
                p = (i-1) * 4;
                accRow(p + 1) = bitand(app.eeMLX90640(35 + (i-1)), hex2dec('000F'));
                accRow(p + 2) = bitsrl(int32(bitand(app.eeMLX90640(35 + (i-1)), hex2dec('00F0'))), 4);
                accRow(p + 3) =  bitsrl(int32(bitand(app.eeMLX90640(35 + (i-1)), hex2dec('0F00'))),8);
                accRow(p + 4) =  bitsrl(int32(bitand(app.eeMLX90640(35 + (i-1)), hex2dec('F000'))), 12);
            end
            
            for i = 1: 24
                
                if (accRow(i) > 7)
                    
                    accRow(i) = accRow(i) - 16;
                end
            end
            
            for i = 1: 8
                
                p = (i-1) * 4;
                accColumn(p + 1) = bitand(app.eeMLX90640(41 + (i-1)),hex2dec('000F'));
                accColumn(p + 2) = bitsrl(int32(bitand(app.eeMLX90640(41 + (i-1)),hex2dec('00F0'))), 4);
                accColumn(p + 3) = bitsrl(int32(bitand(app.eeMLX90640(41 + (i-1)), hex2dec('0F00'))), 8);
                accColumn(p + 4) = bitsrl(int32(bitand(app.eeMLX90640(41 + (i-1)),hex2dec('F000'))), 12);
            end
            
            for i = 1: 32
                
                if (accColumn(i) > 7)
                    
                    accColumn(i) = accColumn(i) - 16;
                    
                end
            end
            
            
            for i=1:24
                
                for j=1:32
                    p = (32 *(i-1)) +j;
                    alpha(p) = double(bitsrl(int32(bitand(app.eeMLX90640(65 + (p-1)),hex2dec('03F0'))), 4));
                    if (alpha(p) > 32)
                        alpha(p) = alpha(p) - 64;
                    end
                    alpha(p) = alpha(p)*bitsll(1 , accRemScale);
                    alpha(p) = (alphaRef + bitsll(accRow(i), accRowScale) + bitsll(accColumn(j), accColumnScale) + alpha(p));
                    alpha(p) = alpha(p) / pow2(double(alphaScale));
                end
            end
            result=alpha;
            
        end
        
   %-----------------ExtractOffsetParameters---------     
        function result= ExtractOffsetParameters(app)
            
            occRow=zeros(1,24);
            occColumn=zeros(1,32);
            offset=zeros(1,768);
            
            occRemScale = bitand(app.eeMLX90640(17),hex2dec('000F'));
            occColumnScale = bitsrl(int32(bitand(app.eeMLX90640(17), hex2dec('00F0'))), 4);
            occRowScale = bitsrl(int32(bitand(app.eeMLX90640(17),hex2dec( '0F00'))), 8);
            offsetRef = app.eeMLX90640(18);
            if (offsetRef > 32767)
                
                offsetRef = offsetRef - 65536;
            end
            
            for  i = 1: 6
                
                p = (i-1) * 4;
                occRow(p + 1) = bitand(app.eeMLX90640(18 + i),hex2dec('000F'));
                occRow(p + 2) = bitsrl(int32(bitand(app.eeMLX90640(18 + i),hex2dec('00F0'))), 4);
                occRow(p + 3) =  bitsrl(int32(bitand(app.eeMLX90640(18 + i),hex2dec('0F00'))), 8);
                occRow(p + 4) =  bitsrl(int32(bitand(app.eeMLX90640(18 + i),hex2dec('F000'))), 12);
            end
            
            for i=1:24
                
                if (occRow(i) > 7)
                    
                    occRow(i) = occRow(i) - 16;
                end
            end
            
            for i=1:8
                
                p = (i-1) * 4;
                occColumn(p + 1) = bitand((app.eeMLX90640(24 + i)) , hex2dec('000F'));
                occColumn(p + 2) =  bitsrl(int32(bitand(app.eeMLX90640(24 + i),hex2dec('00F0'))),4);
                occColumn(p + 3) =  bitsrl(int32(bitand(app.eeMLX90640(24 + i),hex2dec('0F00'))), 8);
                occColumn(p + 4) = bitsrl(int32(bitand(app.eeMLX90640(24 + i) ,hex2dec('F000'))), 12);
            end
            
            for i=1:32
                
                if (occColumn(i) > 7)
                    
                    occColumn(i) = occColumn(i) - 16;
                end
            end
            
            for i=1:24
                
                for j=1:32
                    
                    p = 32 * (i-1) +j;
                    offset(p) = bitsrl(int32(bitand(app.eeMLX90640(65 + (p-1)),hex2dec('FC00'))),10);
                    if (offset(p) > 31)
                        offset(p) = offset(p) - 64;
                    end
                    offset(p) = offset(p)*bitsll(1 , occRemScale);
                    offset(p) = (offsetRef + bitsll(occRow(i) , occRowScale) + bitsll(occColumn(j) , occColumnScale) + offset(p));
                end
            end
            result=  offset;
        end
    
        %---------------------ExtractKtaPixelParameters------------------------------------------------------------
        
        function result=ExtractKtaPixelParameters(app)
            
            
            KtaRC=zeros(1,4);
            kta=zeros(1,768);
            
            KtaRoCo = bitsrl(int32(bitand(app.eeMLX90640(55), hex2dec('FF00'))), 8);
            if (KtaRoCo > 127)
                
                KtaRoCo = KtaRoCo - 256;
            end
            KtaRC(1) = KtaRoCo;
            
            KtaReCo = bitand(app.eeMLX90640(55), hex2dec('00FF'));
            if (KtaReCo > 127)
                
                KtaReCo = KtaReCo - 256;
            end
            KtaRC(3) = KtaReCo;
            
            KtaRoCe = bitsrl(int32(bitand(app.eeMLX90640(56), hex2dec('FF00'))),8);
            if (KtaRoCe > 127)
                
                KtaRoCe = KtaRoCe - 256;
            end
            KtaRC(2) = KtaRoCe;
            
            KtaReCe = bitand(app.eeMLX90640(56), hex2dec('00FF'));
            if (KtaReCe > 127)
                
                KtaReCe = KtaReCe - 256;
            end
            KtaRC(4) = KtaReCe;
            
            ktaScale1 = (bitsrl(int32(bitand(app.eeMLX90640(57) , hex2dec('00F0'))), 4)) + 8;
            ktaScale2 = bitand(app.eeMLX90640(57) , hex2dec('000F'));
            
            for i = 1: 24
                
                for  j = 1: 32
                    
                    p = 32 * (i-1) +j;
                    split= 2*(floor(p/32) - floor(p/64)*2) + mod(p,2);
                    split=split+1;
                    kta(p) = bitsrl(int32(bitand(app.eeMLX90640(64 + p), hex2dec('000E'))), 1);
                    if (kta(p) > 3)
                        
                        kta(p) = kta(p) - 8;
                    end
                    kta(p) = kta(p) * bitsll(1 , ktaScale2);
                    kta(p) = KtaRC(split) + kta(p);
                    kta(p) = kta(p) / pow2(double(ktaScale1));
                end
            end
            result=kta;
        end
        %%----------------------------ExtractKvPix--------------------------------------------------
        
        function result=ExtractKvPixelParameters(app)
            
            KvT=zeros(1,4);
            split=0;
            kv=zeros(1,768);
            KvRoCo = bitsrl(int32(bitand(app.eeMLX90640(53), hex2dec('F000'))), 12);
            if (KvRoCo > 7)
                
                KvRoCo = KvRoCo - 16;
            end
            KvT(1) = KvRoCo;
            
            KvReCo = bitsrl(int32(bitand(app.eeMLX90640(53), hex2dec('0F00'))), 8);
            if (KvReCo > 7)
                
                KvReCo = KvReCo - 16;
            end
            KvT(3)= KvReCo;
            
            KvRoCe = bitsrl(int32(bitand(app.eeMLX90640(53),hex2dec('00F0'))), 4);
            if (KvRoCe > 7)
                
                KvRoCe = KvRoCe - 16;
            end
            KvT(2) = KvRoCe;
            
            KvReCe = bitand(app.eeMLX90640(53),hex2dec('000F'));
            if (KvReCe > 7)
                
                KvReCe = KvReCe - 16;
            end
            KvT(4) = KvReCe;
            
            kvScale = bitsrl(int32(bitand(app.eeMLX90640(57),hex2dec('0F00'))) ,8);
            
            
            for i = 1: 24
                
                for j = 1: 32
                    p = 32 * (i-1) +j;
                    split= 2*(floor(p/32) - floor(p/64)*2) + mod(p,2);
                    split=split+1;
                    kv(p) = KvT(split);
                    kv(p) = kv(p) / pow2(double(kvScale));
                end
            end
            result=kv;
        end
        %%---------------------------------ExtractCPP--------------------------------------------
        function result= ExtractCPParameters(app)
            
            alphaSP=zeros(1,2);
            offsetSP=zeros(1,2);
            alphaScale = bitsrl(int32(bitand(app.eeMLX90640(32), hex2dec('F000'))), 12) + 27;
            
            offsetSP(1) = bitand(app.eeMLX90640(59), hex2dec('03FF'));
            if (offsetSP(1) > 511)
                
                offsetSP(1) = offsetSP(1) - 1024;
            end
            
            offsetSP(2) =  bitsrl(int32(bitand(app.eeMLX90640(59), hex2dec('FC00'))), 10);
            if (offsetSP(2) > 31)
                
                offsetSP(2) = offsetSP(2) - 64;
            end
            offsetSP(2) = offsetSP(2) + offsetSP(1);
            
            alphaSP(1) = bitand(app.eeMLX90640(58), hex2dec('03FF'));
            if (alphaSP(1) > 511)
                
                alphaSP(1) = alphaSP(1) - 1024;
            end
            alphaSP(1) = alphaSP(1) /  pow2(double(alphaScale));
            
            alphaSP(2) =  bitsrl(int32(bitand(app.eeMLX90640(58) ,hex2dec('FC00'))), 10);
            if (alphaSP(2) > 31)
                
                alphaSP(2) = alphaSP(2) - 64;
            end
            alphaSP(2) = (1 + alphaSP(2)/128) * alphaSP(1);
            
            cpKta = bitand(app.eeMLX90640(60) , hex2dec('00FF'));
            if (cpKta > 127)
                
                cpKta = cpKta - 256;
            end
            ktaScale1 =  bitsrl(int32(bitand(app.eeMLX90640(57) , hex2dec('00F0'))), 4) + 8;
            cpKta = cpKta / pow2(double (ktaScale1));
            
            cpKv =  double(bitsrl(int32(bitand(app.eeMLX90640(60),hex2dec('FF00'))), 8));
            if (cpKv > 127)
                
                cpKv = cpKv - 256;
            end
            kvScale =  bitsrl(int32(bitand(app.eeMLX90640(57), hex2dec('0F00'))), 8);
            cpKv = cpKv / pow2(double(kvScale));
            
            cpAlpha(1) = alphaSP(1);
            cpAlpha(2) = alphaSP(2);
            cpOffset(1) = offsetSP(1);
            cpOffset(2) = offsetSP(2);
            
            temp(1)=cpAlpha(1);
            temp(2)=cpAlpha(2);
            temp(3)= cpOffset(1);
            temp(4)=cpOffset(2);
            temp(5)=cpKv;
            temp(6)=cpKta;
            result=temp;
        end
        
        %%---------------------------ExtractCILC----------------------------------------------
        function result= ExtractCILCParameters(app)
            
            ilChessC=zeros(1,3);
            calibrationModeEE = double(bitsrl(int32(bitand(app.eeMLX90640(11),hex2dec('0800'))), 4));
            calibrationModeEE = bitxor(calibrationModeEE, hex2dec('80'));
            
            ilChessC(1) = bitand(app.eeMLX90640(54) , hex2dec('003F'));
            if (ilChessC(1) > 31)
                
                ilChessC(1) = ilChessC(1) - 64;
            end
            ilChessC(1) = ilChessC(1) / 16.0;
            
            ilChessC(2) = double(bitsrl(int32(bitand(app.eeMLX90640(54), hex2dec('07C0'))), 6));
            if (ilChessC(2) > 15)
                
                ilChessC(2) = ilChessC(2) - 32;
            end
            ilChessC(2) = ilChessC(2) / 2.0;
            
            ilChessC(3) = double(bitsrl(int32(bitand(app.eeMLX90640(54), hex2dec('F800'))) , 11));
            if (ilChessC(3) > 15)
                
                ilChessC(3) = ilChessC(3) - 32;
            end
            ilChessC(3) = ilChessC(3) / 8.0;
            
            temp(1) = calibrationModeEE;
            temp(2) = ilChessC(1);
            temp(3) = ilChessC(2);
            temp(4) = ilChessC(3);
            result=temp;
        end
        
        %%---------------------------------ExtractDeviatingPixels------------------------------------------------------
        function result= ExtractDeviatingPixels(app)
            
            brokenPixCnt = 1;
            outlierPixCnt = 1;
            warn = 0;
            temp=zeros(1,11);
            brokenPixels=zeros(1,5);
            outlierPixels=zeros(1,5);
            for pixCnt = 1:5
                
                brokenPixels(pixCnt) = hex2dec('FFFF');
                outlierPixels(pixCnt) = hex2dec('FFFF');
            end
            
            for i = 1:5
                
                temp(i)= brokenPixels(i);
                
            end
            
            for i = 6:10
                
                temp(i)= outlierPixels(i-5);
                
            end
            
            pixCnt = 1;
            while (pixCnt < 769 && brokenPixCnt < 6 && outlierPixCnt < 6)
                
                if(app.eeMLX90640(pixCnt+64) == 0)
                    
                    brokenPixels(brokenPixCnt) = pixCnt;
                    brokenPixCnt = brokenPixCnt + 1;
                    
                elseif(bitand(app.eeMLX90640(pixCnt+64),hex2dec('0001'))== 0)
                    
                    outlierPixels(outlierPixCnt) = pixCnt;
                    outlierPixCnt = outlierPixCnt + 1;
                end
                
                pixCnt = pixCnt + 1;
                
            end
            
            if(brokenPixCnt > 5)
                
                warn = -3;
                
            elseif(outlierPixCnt > 5)
                
                warn = -4;
                
            elseif((brokenPixCnt + outlierPixCnt) > 5)
                
                warn = -5;
                
            else
                
                for pixCnt=1:brokenPixCnt
                    
                    for i=(pixCnt+1) :brokenPixCnt
                        
                        warn = app.CheckAdjacentPixels(brokenPixels(pixCnt),brokenPixels(i));
                        if(~(warn == 0))
                            
                            temp(11)=warn;
                            result=temp;
                        end
                    end
                end
                
                
                for pixCnt=1:outlierPixCnt
                    
                    for i=pixCnt+1: outlierPixCnt
                        
                        warn = app.CheckAdjacentPixels(outlierPixels(pixCnt),outlierPixels(i));
                        if(~(warn == 0))
                            
                            temp(11)= warn;
                            result=temp;
                            
                        end
                        
                    end
                end
                
                for pixCnt=0 :brokenPixCnt
                    
                    for i=0 :outlierPixCnt
                        
                        warn = app.CheckAdjacentPixels(brokenPixels(pixCnt),outlierPixels(i));
                        if(~(warn == 0))
                            temp(11)=warn;
                            result= temp;
                        end
                    end
                end
                
            end
            
            
            result=temp;
            
        end
        
        %%-----------------------------CheckAdjacentPixels-------------------------------------------------
        
        function result= CheckAdjacentPixels(app,pix1, pix2)
            
            pixPosDif = pix1 - pix2;
            if(pixPosDif > -34 && pixPosDif < -30)
                
                result= -6;
                
            elseif(pixPosDif > -2 && pixPosDif < 2)
                
                result= -6;
                
            elseif(pixPosDif > 30 && pixPosDif < 34)
                
                result=-6;
            else
                
                result=0;
            end
        end
 %---------------------------------Computation Part-----------------------------------------------------------

function result=MLX90640_CalculateTo(app)

    irDataCP=zeros(1,2);
    alphaCorrR=zeros(1,4);
    TA_SHIFT=8;
    emissivity = 0.95;
    %fprintf("------Calculating & storing-----\n");
    for x=1:2
        %res=sscanf(fscanf(app.s1),"%d");
       
        %app.frameData=sscanf( fgetl(app.s1),"%d");
        fread(app.s1,1,'uint8');
        app.tmpData = fread(app.s1, 451, 'uint16');
        app.ADC=app.tmpData(451);
        
        if app.tmpData(450)==0
        for k=1:384
            var=((k-1)*2)+1+mod(floor(((k-1)/16)),2);
            app.frameData(var)=app.tmpData(k);
        end
        else 
            for k=1:384
            var=((k-1)*2)+2-mod(floor(((k-1)/16)),2);
            app.frameData(var)=app.tmpData(k);
            end
        end
        index=385;
        for z=769:835
            
            app.frameData(z)=app.tmpData(index);
            index=index+1;
        end
        
        %app.frameData = res;
        %---------------------------
        subPage = app.frameData(834);
        vdd = app.MLX90640_GetVdd();
        Ta = app.MLX90640_GetTa();
        tr = Ta - TA_SHIFT;  %Reflected temperature based on the sensor ambient temperature
        ta4 = mpower((Ta + 273.15), double(4));
        tr4 = mpower((tr + 273.15), double(4));
        taTr = tr4 - (tr4-ta4)/emissivity;
        
        
        alphaCorrR(1) = 1 / (1 + app.structVar.ksTo(1) * 40);
        alphaCorrR(2) = 1 ;
        alphaCorrR(3) = (1 + app.structVar.ksTo(3) * app.structVar.ct(3) );
        alphaCorrR(4) = alphaCorrR(3) * (1 + app.structVar.ksTo(4) * (app.structVar.ct(4) - app.structVar.ct(3) ));
        
        %--------------Gain calculation------------
        
        gain = app.frameData(779);
        if(gain > 32767)
            
            gain = gain - 65536;
        end
        
        gain = app.structVar.gainEE / gain;
        
        
        %--------------To calculation------------
        mode = bitsrl(int32(bitand(app.frameData(833),hex2dec('1000'))),5);
        irDataCP(1) = app.frameData(777);
        irDataCP(2) = app.frameData(809);
        %for i = 1:2
            
            if(irDataCP > 32767)
                
                irDataCP = irDataCP - 65536;
            end
            irDataCP = irDataCP * gain;
        %end
        irDataCP(1) = irDataCP(1) - app.structVar.cpOffset(1) * (1 + app.structVar.cpKta * (Ta - 25)) * (1 + app.structVar.cpKv * (vdd - 3.3));
        if( mode ==  app.structVar.calibrationModeEE)
            
            irDataCP(2) = irDataCP(2) - app.structVar.cpOffset(2) * (1 + app.structVar.cpKta * (Ta - 25)) * (1 + app.structVar.cpKv * (vdd - 3.3));
            
        else
            
            irDataCP(2) = irDataCP(2) - (app.structVar.cpOffset(2) + app.structVar.ilChessC(1)) * (1 + app.structVar.cpKta * (Ta - 25)) * (1 + app.structVar.cpKv * (vdd - 3.3));
        end
        for pixelNumber = 1:768
            
            ilPattern =floor(( pixelNumber-1) / 32) - floor((pixelNumber-1)/ 64) * 2;
            
            chessPattern = bitxor(ilPattern , ((pixelNumber-1) - (floor((pixelNumber-1)/2))*2));
            
            conversionPattern = (floor(((pixelNumber-1) + 2) / 4) - floor(((pixelNumber-1) + 3) / 4) + floor(((pixelNumber-1) + 1) / 4) - floor((pixelNumber-1) / 4)) * (1 - 2 * ilPattern);
            if(mode == 0)
                
                pattern = ilPattern;
                
            else
                
                pattern = chessPattern;
            end
            if(pattern == app.frameData(834))
                
                irData = app.frameData(pixelNumber);
                if(irData > 32767)
                    
                    irData = irData - 65536;
                end
                irData = irData * gain;
                irData = irData - app.structVar.offset(pixelNumber)*(1 + app.structVar.kta(pixelNumber)*(Ta - 25))*(1 + app.structVar.kv(pixelNumber)*(vdd - 3.3));
                if(~(mode ==  app.structVar.calibrationModeEE))
                    
                    irData = irData + app.structVar.ilChessC(3) * (2 * ilPattern - 1) - app.structVar.ilChessC(2) * conversionPattern;
                end
                
                irData = irData / emissivity;
                
                irData = irData - app.structVar.tgc * irDataCP(subPage+1);
                
                alphaCompensated = double(app.structVar.alpha(pixelNumber) - app.structVar.tgc * app.structVar.cpAlpha(subPage+1))*(1 + app.structVar.KsTa * (Ta - 25));
                
                Sx = double(mpower(double(alphaCompensated), double(3)) * (irData + alphaCompensated * taTr));
                Sx = double(sqrt(double(sqrt(Sx))) * app.structVar.ksTo(2));
                temp=sqrt(irData/double(alphaCompensated * (1 - app.structVar.ksTo(2) * 273.15)+ Sx)+ taTr);
                To = sqrt(temp) - 273.15;
                
                if(To < app.structVar.ct(2))
                    
                    range = 1;
                    
                elseif(To < app.structVar.ct(3))
                    
                    range = 2;
                    
                elseif(To < app.structVar.ct(4))
                    
                    range =3;
                    
                else
                    
                    range = 4;
                end
                
                To = sqrt(double(sqrt(double(irData / (alphaCompensated * alphaCorrR(range) * (1 + app.structVar.ksTo(range) * (To - app.structVar.ct(range)))) + taTr)))) - 273.15;
                
                app.tempData(pixelNumber) = To;
                
            end
        end
        
    end
    result=app.tempData;

end


%%------Vdd------------
function result = MLX90640_GetVdd(app)
 
    vdd = app.frameData(811);
    if(vdd > 32767)
    
        vdd = vdd - 65536;
    end
    resolutionRAM = bitsrl(int32(bitand(app.frameData(833), hex2dec('0C00'))), 10);
    resolutionCorrection = double(pow2(double(app.structVar.resolutionEE)) / pow2(double(resolutionRAM)));
    vdd = double(resolutionCorrection * vdd - app.structVar.vdd25) / double(app.structVar.kVdd) + 3.3;
    
    result=vdd;
end
%%------------Ta---------
function result= MLX90640_GetTa(app)
    
    vdd = app.MLX90640_GetVdd();
    
    ptat = app.frameData(801);
    if(ptat > 32767)
        
        ptat = ptat - 65536;
    end
    
    ptatArt = app.frameData(769);
    if(ptatArt > 32767)
        
        ptatArt = ptatArt - 65536;
    end
    temp=ptat * app.structVar.alphaPTAT + ptatArt;
    ptatArt =  (double(ptat) / double(temp)) * pow2(double(18));
    
    ta = ptatArt / (1 + app.structVar.KvPTAT * (vdd - 3.3)) - app.structVar.vPTAT25;
    ta = (double(ta) /double( app.structVar.KtPTAT)) + 25;
    
    result= ta;
end
  %-------------UDP callback function------------
       function callbackFcn(app,UDPComIn)
            msg=fscanf(UDPComIn);
            if(strcmpi(msg,'start'))
               %close the previous file
                %fclose( app.framefileID);
                %change filenumber for every onClickstart
                app.fileNumber=app.fileNumber+1;
                 if(app.fileNumber>1)
                    fclose(app.s1);
                     fclose(app.framefileID);
                     fopen(app.s1);
                end
                 app.fileName=sprintf('D:\\Thesis\\firmware\\Tue181218\\test%d.csv',app.fileNumber);
                  save(app.fileName);
                %app.fileName='D:\\Thesis\\firmware\\Sat151218\\staticpoint18.csv';
                app.framefileID = fopen(app.fileName,'w');
                %change all the flag status inorder to continue display and data record untill stop msg
                app.flag=1;
                app.Startflag=1;
                app.pktCnt=0; %start packet counter only for validation purposes
                fprintf('Started Serial Read \n');
                app.tmprData=app.MLX90640_CalculateTo();
                %for p=1:768
                    fprintf(app.framefileID, '%2.2f,',app.tmprData);
                %end
                fprintf(app.framefileID,"\n");
                for k=1:24
                    for l=1:32
                        app.imData(k,l)=app.tmprData(((k-1)*32)+l);
                    end
                end
                
                app.I.CData(:)= app.imData(:);
                pause(0.001);
                %drawnow limitrate;
                %drawnow;
                app.pktCnt=app.pktCnt+1;
                fprintf("%d \n",app.pktCnt);
            elseif(strcmpi(msg,'stop'))
                fprintf('Stopped Serial read \n');
                app.flag=0; % flag and Startflag combination must be unique use it in while loop to reinitialise all the serial port variables
                app.Startflag=1;
            elseif(strcmpi(msg,'close'))
                if(app.flag==1)
                    fclose(app.s1);
                    delete(app.s1);
                    fprintf('Closing Serial port first\n');
                end
                fprintf('Closed UDP port \n');
                app.flag=0;
                app.Startflag=0;
                
            end
            
       end
        
 end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Close the UDP socket if open
            instrreset;
            %Close the serial port if open
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            %eeprom data fetch
            app.s1 = serial('COM4');    %define serial port to read the Arduino
            app.s1.BaudRate=256000;     %define baud rate
            app.s1.InputBufferSize=2^21;
            app.s1.Terminator = 'CR';
            fopen(app.s1);
            app.s1.ReadAsyncMode = 'continuous';
            app.eeMLX90640=app.ReceiveEEPROMData();
             %calibration paramters
            app.structVar=app.MLX90640_ExtractParameters();
            fclose(app.fileID);
            
             %Initialize the imagesc handle
                app.I=imagesc(app.imData,'Parent',app.UIAxes);
                colormap(app.UIAxes,jet(256));
                %colorbar(app.UIAxes);
            %setting UDP socket properties
            UDPComIn=udp('145.94.233.73','LocalPort',6666,'Timeout',0.1); % ewi
           %UDPComIn=udp('192.168.0.104','LocalPort',6666,'Timeout',0.1); % home
            set(UDPComIn,'DatagramTerminateMode','on');
           % fopen(UDPComIn);
            app.fileName='D:\\Thesis\\firmware\\Tue181218\\test.csv';
                  
                %app.fileName='D:\\Thesis\\firmware\\Sat151218\\staticpoint18.csv';
              %  app.framefileID = fopen(app.fileName,'w');
            %---------capturing raw data onClickStart--------
            flushinput(app.s1);
            fprintf(app.s1,8);
            while(app.Startflag==1)
                
           % UDPComIn.DatagramReceivedFcn = @(src,event)app.callbackFcn(UDPComIn);
            if(app.flag==1)
                
               % fprintf('Continue Serial read \n');
                app.flag=1;
                app.Startflag=1;
                
               
                app.tmprData=app.MLX90640_CalculateTo();
                %for p=1:768
                   % fprintf(app.framefileID, '%2.2f,',app.tmprData);
                %end
                fprintf(app.framefileID,"\n");
                for k=1:24
                    for l=1:32
                        app.imData(k,l)=app.tmprData(((k-1)*32)+l);
                    end
                end
                
                app.I.CData(:)= app.imData(:);
                pause(0.001);
                %drawnow limitrate;
                %drawnow;
                app.pktCnt=app.pktCnt+1;
                fprintf("%d \n",app.pktCnt);
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EComUDP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end